classdef VIONWAKE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SimulationControlPanel     matlab.ui.container.Panel
        TraceButton                matlab.ui.control.StateButton
        RecordLamp                 matlab.ui.control.Lamp
        RestartButton              matlab.ui.control.StateButton
        RecordButton               matlab.ui.control.StateButton
        PlayLamp                   matlab.ui.control.Lamp
        PlayButton                 matlab.ui.control.StateButton
        SimulationParametersPanel  matlab.ui.container.Panel
        PressurePaSpinner          matlab.ui.control.Spinner
        PressurePaSpinnerLabel     matlab.ui.control.Label
        GravitySpinner             matlab.ui.control.Spinner
        GravitySpinnerLabel        matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Simulation parameters
        BLOCK                   = 32;
        GAS_TEMPERATURE         = 288.15;
        GAS_PRESSURE            = 10;
        ION_MASS                = 6.68e-26;
        ELECTRON_CHARGE         = -1.60e-19;
        LENGTH_DEBYE            = 90e-6;
        SHEATH_HEIGHT           = 10.6e-3;
        WAKE_CHARGE_PERCENT     = 0.0;
        WAKE_LENGTH             = 45e-6;
        CUTOFF_MULTIPLIER       = 6;
        
        NUM_PARTICLES           = 200;
        DUST_DENSITY            = 1.51 * 1e3;
        DUST_DIAMETER_MEAN      = 8.89e-6;
        DUST_RADIUS_MEAN
        DUST_DIAMETER_STD       = 0.01e-6;
        DUST_CHARGE_DENSITY_MEAN= 1000 * 1e6;
        DUST_CHARGE_DENSITY_STD = 20 * 1e6;
        
        COULOMB                 = 8.98e12;
        BOLTZMANN               = 1.38e-23;
        GRAVITY                 = 9.8;
        CELL_CHARGE             = 1.6e2;
        CELL_RADIUS             = 0.6e-2;
        CELL_HEIGHT             = 1.2e-2;
        E_0                     = 3350;
        
        TIME_STEP               = 0.0005;
        RATE_DRAW               = 20;
        RATE_PRINT              = 200;
        
        TOTAL_TIME              = 0;

        % Simulation variables
        dust;
        wake;
        plasma; 

        % Callbacks
        play                    = false;
        trace                   = false;

        % Cuda kernel objects
        pcryAcc;
        pcryPos;

        % Axes objects
        dustAxes;
        wakeAxes;
        traceAxes;
    end
    
    methods (Access = private)
        % DRAW CELL AND SHEATH=============================================
        function drawCell(app)
            % Set axis limits
            app.UIAxes.XLim = [-app.CELL_RADIUS app.CELL_RADIUS];
            app.UIAxes.YLim = [-app.CELL_RADIUS app.CELL_RADIUS];
            app.UIAxes.ZLim = [0 app.CELL_HEIGHT];
            
            % Draw cell electrodes and sheath
            theta = linspace(0,2*pi,30);

            hold(app.UIAxes,'on')
            plot3(app.UIAxes,app.CELL_RADIUS * cos(theta),...
                            app.CELL_RADIUS * sin(theta),...
                            zeros(numel(theta),1),'k');

            plot3(app.UIAxes,app.CELL_RADIUS * cos(theta),...
                            app.CELL_RADIUS * sin(theta),...
                            repmat(app.CELL_HEIGHT,[numel(theta) 1]),'k');

            plot3(app.UIAxes,app.CELL_RADIUS * cos(theta),...
                            app.CELL_RADIUS * sin(theta),...
                            repmat(app.SHEATH_HEIGHT,[numel(theta) 1]),'m');

            hold(app.UIAxes,'off')

            % Label the axes
            xlabel(app.UIAxes,'x [m]')
            ylabel(app.UIAxes,'y [m]')
            zlabel(app.UIAxes,'z [m]')

            title(app.UIAxes,'CELL')

            view(app.UIAxes,3)
        end
        
        % DRAW DUST AND WAKE PARTICLES=====================================
        function drawDust(app)
            [x,y,z] = gather(app.dust.x,app.dust.y,app.dust.z);

            hold(app.UIAxes,'on')
            app.dustAxes = scatter3(app.UIAxes,...
                                   x,...
                                   y,...
                                   z, ...
                                   50,'.k');
            app.wakeAxes = scatter3(app.UIAxes,...
                                   x,...
                                   y,...
                                   z-app.WAKE_LENGTH,...
                                   30,'.r');
            hold(app.UIAxes,'off')
        end
        
        % DRAW PARTICLE==============================================
        function drawTrace(app,x,y,z)
            if app.trace == true
                hold(app.UIAxes,'on')
                app.traceAxes = scatter3(app.UIAxes,...
                                        x,...
                                        y,...
                                        z,...
                                        1,'.b');
                hold(app.UIAxes,'off')
            end
        end
        
        % INITIALIZE CUDA KERNELS==========================================
        function setCudaKernels(app)
            ThreadBlockSize = app.BLOCK;
            GridSize        = floor((app.NUM_PARTICLES-1)/ThreadBlockSize) + 1;
            
            ! nvcc -ptx pcry.cu
            acc = parallel.gpu.CUDAKernel('pcry.ptx','pcry.cu','pcryCalculate_ACC');
            pos = parallel.gpu.CUDAKernel('pcry.ptx','pcry.cu','pcryCalculate_POS');
            
            acc.GridSize        = [GridSize 1 1];
            pos.GridSize        = [GridSize 1 1];
            acc.ThreadBlockSize = [ThreadBlockSize 1 1];
            pos.ThreadBlockSize = [ThreadBlockSize 1 1];

            app.pcryAcc = acc;
            app.pcryPos = pos;
        end

        % GENERATE DUST PARTICLES==========================================
        function generateParticles(app)
            % GENERATE PARTICLE POSITIONS
            % Allocate memory
            xx = zeros(app.NUM_PARTICLES,1,'single');
            yy = zeros(app.NUM_PARTICLES,1,'single');
            zz = zeros(app.NUM_PARTICLES,1,'single');

            x = 0;
            y = 0;
            z = 0;

            for i = 1:app.NUM_PARTICLES
                flag = true;

                % Generate random cartesian coordinates
                while (flag == true)
                    x   = rand('single') * (2 * app.CELL_RADIUS) - app.CELL_RADIUS;
                    y   = rand('single') * (2 * app.CELL_RADIUS) - app.CELL_RADIUS;
                    z   = rand('single') * (app.CELL_HEIGHT * 0.5) + app.CELL_HEIGHT * (1 - 0.5);
                    
                    % Check for particles that are too close
                    f1 = any(((xx(1:i-1)-x).^2 + (yy(1:i-1)-y).^2 + (zz(1:i-1)-z).^2) < (10000e-12));

                    % Check for particles outside the cell
                    f2 = (x^2 + y^2) >= (app.CELL_RADIUS^2);

                    flag = (f1) || (f2);
                end
                
                % Save coordinates
                xx(i) = x;
                yy(i) = y;
                zz(i) = z;
            end

            % GENERATE PARTICLE VELOCITIES
            vx = zeros(app.NUM_PARTICLES,1,'single');
            vy = zeros(app.NUM_PARTICLES,1,'single');
            vz = zeros(app.NUM_PARTICLES,1,'single');
            
            ax = zeros(app.NUM_PARTICLES,1,'single');
            ay = zeros(app.NUM_PARTICLES,1,'single');
            az = zeros(app.NUM_PARTICLES,1,'single');
            
            % Generate particle sizes, masses and charges
            diameter = (randn(app.NUM_PARTICLES,1,'single') * app.DUST_DIAMETER_STD + app.DUST_DIAMETER_MEAN);
            radius   = diameter / 2;
            mass     = app.DUST_DENSITY * (4/3 * pi * radius.^3);
            charge   = round(app.DUST_CHARGE_DENSITY_MEAN * diameter) * app.ELECTRON_CHARGE;

            % Generate wake attributes
            wake_charge_p           = app.WAKE_CHARGE_PERCENT * ones(app.NUM_PARTICLES,1,'single');
            wake_length             = app.WAKE_LENGTH * ones(app.NUM_PARTICLES,1,'single');
            wake_nn_r               = 1000 * app.LENGTH_DEBYE * ones(app.NUM_PARTICLES,1,'single');
            wake_nn_z               = 1000 * app.LENGTH_DEBYE * ones(app.NUM_PARTICLES,1,'single');
            wake_nn_id              = -1 * ones(app.NUM_PARTICLES,1,'int32');
        
            % Save variables
            app.dust.x              = gpuArray(xx);
            app.dust.y              = gpuArray(yy);
            app.dust.z              = gpuArray(zz);
            app.dust.vx             = gpuArray(vx);
            app.dust.vy             = gpuArray(vy);
            app.dust.vz             = gpuArray(vz);
            app.dust.ax             = gpuArray(ax);
            app.dust.ay             = gpuArray(ay);
            app.dust.az             = gpuArray(az);
            app.dust.diameter       = gpuArray(diameter);
            app.dust.radius         = gpuArray(radius);
            app.dust.charge         = gpuArray(charge);
            app.dust.mass           = gpuArray(mass);
            
            app.wake.charge         = gpuArray(wake_charge_p);
            app.wake.length         = gpuArray(wake_length);
            app.wake.nn_r           = gpuArray(wake_nn_r);
            app.wake.nn_z           = gpuArray(wake_nn_z);
            app.wake.nn_id          = gpuArray(wake_nn_id);
        end

        function calculatePlasmaParams(app)
            app.plasma.BETA = 30;

            app.DUST_RADIUS_MEAN = app.DUST_DIAMETER_MEAN / 2;
        end

        % CALCULATE TIMESTEP===============================================
        function calculateTimeStep(app)
            % CALCULATE THE FORCES
            [app.dust.x,app.dust.y,app.dust.z,...
             app.dust.vx,app.dust.vy,app.dust.vz,...
             app.dust.ax,app.dust.ay,app.dust.az,...
             app.dust.radius,...
             app.dust.mass,...
             app.dust.charge,...
             app.wake.charge,...
             app.wake.length,...
             app.wake.nn_z,...
             app.wake.nn_r,...
             app.wake.nn_id] = feval(app.pcryAcc,...
                                app.dust.x,app.dust.y,app.dust.z,...
                                app.dust.vx,app.dust.vy,app.dust.vz,...
                                app.dust.ax,app.dust.ay,app.dust.az,...
                                app.dust.radius,...
                                app.dust.mass,...
                                app.dust.charge,...
                                app.wake.charge,...
                                app.wake.length,...
                                app.wake.nn_z,...
                                app.wake.nn_r,...
                                app.wake.nn_id,...
                                app.DUST_RADIUS_MEAN,...
                                app.COULOMB,...
                                app.LENGTH_DEBYE,...
                                app.CUTOFF_MULTIPLIER,...
                                app.CELL_RADIUS,...
                                app.CELL_CHARGE,...
                                app.CELL_HEIGHT,...
                                app.SHEATH_HEIGHT,...
                                app.WAKE_CHARGE_PERCENT,...
                                app.GRAVITY,...
                                app.GAS_TEMPERATURE,...
                                app.GAS_PRESSURE,...
                                app.TIME_STEP,...
                                app.NUM_PARTICLES);

            % CALCULATE POSITIONS
            fprintf("Charge: %e ",gather(app.dust.charge(1)));
            fprintf("Position: %e ",gather(app.dust.z(1)));
            fprintf("Acc: %e \n",gather(app.dust.az(1)));

            [app.dust.x,app.dust.y,app.dust.z,...
             app.dust.vx,app.dust.vy,app.dust.vz,...
             app.dust.ax,app.dust.ay,app.dust.az,...
             app.dust.radius,...
             app.dust.mass,...
             app.dust.charge,...
             app.wake.charge,...
             app.wake.length,...
             app.wake.nn_z,...
             app.wake.nn_r,...
             app.wake.nn_id] = feval(app.pcryPos, ...
                                app.dust.x,app.dust.y,app.dust.z,...
                                app.dust.vx,app.dust.vy,app.dust.vz,...
                                app.dust.ax,app.dust.ay,app.dust.az,...
                                app.dust.radius,...
                                app.dust.mass,...
                                app.dust.charge,...
                                app.wake.charge,...
                                app.wake.length,...
                                app.wake.nn_z,...
                                app.wake.nn_r,...
                                app.wake.nn_id,...
                                app.DUST_CHARGE_DENSITY_MEAN,...
                                app.ELECTRON_CHARGE,...
                                app.CUTOFF_MULTIPLIER,...
                                app.WAKE_CHARGE_PERCENT,...
                                app.WAKE_LENGTH,...
                                app.LENGTH_DEBYE,...
                                app.TIME_STEP,...
                                app.TOTAL_TIME,...
                                app.NUM_PARTICLES);

            fprintf("Charge: %e ",gather(app.dust.charge(1)));
            fprintf("Position: %e ",gather(app.dust.z(1)));
            fprintf("Acc: %e \n",gather(app.dust.az(1)));
        end

        % FORMAT CALLBACKS=================================================
        function formatCallbacks(app)
            % GRAVITY SPINNER
            app.GravitySpinner.Value = app.GRAVITY;
            app.GravitySpinner.Step = 0.1;
            app.GravitySpinner.Limits = [0 100];

            % PRESSURE SPINNER
            app.PressurePaSpinner.Value = app.GAS_PRESSURE;
            app.PressurePaSpinner.Step = 0.1;
            app.PressurePaSpinner.Limits = [0.1 200];

            % LAMP
            app.PlayLamp.Color = 'red';
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setCudaKernels(app);
            generateParticles(app);
            calculatePlasmaParams(app);
            formatCallbacks(app)
            drawCell(app);
            drawDust(app);
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            app.play = app.PlayButton.Value;
            i = 0;
            
            % Change the color of the lamp
            if app.play == true
                app.PlayLamp.Color = 'green';
            else
                app.PlayLamp.Color = 'red';
            end

            % Keep the simulation running
            while app.play == true
                % Calculate one timestep
                calculateTimeStep(app)
                
                % REDRAW PARTICLE POSITIONS
                x = gather(app.dust.x);
                y = gather(app.dust.y);
                z = gather(app.dust.z);
                
                % Update dust particles
                app.dustAxes.XData = x;
                app.dustAxes.YData = y;
                app.dustAxes.ZData = z;

                % Update wake particles
                app.wakeAxes.XData = x;
                app.wakeAxes.YData = y;
                app.wakeAxes.ZData = z-app.WAKE_LENGTH;

                drawTrace(app,x,y,z)


                drawnow limitrate
                app.TOTAL_TIME = app.TOTAL_TIME + (i * app.TIME_STEP);
                i = i + 1;
            end
        end

        % Value changing function: GravitySpinner
        function GravitySpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.GRAVITY = changingValue;
        end

        % Value changing function: PressurePaSpinner
        function PressurePaSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.GAS_PRESSURE = changingValue;
        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            record = app.RecordButton.Value;
            
        end

        % Value changed function: TraceButton
        function TraceButtonValueChanged(app, event)
            app.trace = app.TraceButton.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SimulationParametersPanel = uipanel(app.UIFigure);
            app.SimulationParametersPanel.Title = 'Simulation Parameters';
            app.GravitySpinnerLabel = uilabel(app.SimulationParametersPanel);
            app.GravitySpinnerLabel.Text = 'Gravity';
            app.GravitySpinner = uispinner(app.SimulationParametersPanel);
            app.GravitySpinner.ValueChangingFcn = createCallbackFcn(app, @GravitySpinnerValueChanging, true);
            app.PressurePaSpinnerLabel = uilabel(app.SimulationParametersPanel);
            app.PressurePaSpinnerLabel.Text = 'Pressure [Pa]';
            app.PressurePaSpinner = uispinner(app.SimulationParametersPanel);
            app.PressurePaSpinner.ValueChangingFcn = createCallbackFcn(app, @PressurePaSpinnerValueChanging, true);
            app.SimulationControlPanel = uipanel(app.UIFigure);
            app.SimulationControlPanel.Title = 'Simulation Control';
            app.PlayButton = uibutton(app.SimulationControlPanel, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.PlayLamp = uilamp(app.SimulationControlPanel);
            app.RecordButton = uibutton(app.SimulationControlPanel, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Text = 'Record';
            app.RestartButton = uibutton(app.SimulationControlPanel, 'state');
            app.RestartButton.Text = 'Restart';
            app.RecordLamp = uilamp(app.SimulationControlPanel);
            app.TraceButton = uibutton(app.SimulationControlPanel, 'state');
            app.TraceButton.ValueChangedFcn = createCallbackFcn(app, @TraceButtonValueChanged, true);
            app.TraceButton.Text = 'Trace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VIONWAKE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end