classdef VIONWAKE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup01                 matlab.ui.container.TabGroup
        Tab                        matlab.ui.container.Tab
        ParticleTrajectoriesTab    matlab.ui.container.Tab
        UITable                    matlab.ui.control.Table
        Panel                      matlab.ui.container.Panel
        GaugeDebyeIon              matlab.ui.control.LinearGauge
        ElectronDebyeGaugeLabel    matlab.ui.control.Label
        GaugeDebyeElectron         matlab.ui.control.LinearGauge
        IonDebyeGaugeLabel         matlab.ui.control.Label
        SimulationControlPanel     matlab.ui.container.Panel
        NewseedCheckBox            matlab.ui.control.CheckBox
        ReplayButton               matlab.ui.control.Button
        PlayLamp                   matlab.ui.control.Lamp
        PlayButton                 matlab.ui.control.StateButton
        SimulationParametersPanel  matlab.ui.container.Panel
        PowerSpinner               matlab.ui.control.Spinner
        PowerSpinnerLabel          matlab.ui.control.Label
        PressureSpinner            matlab.ui.control.Spinner
        PressureSpinnerLabel       matlab.ui.control.Label
        GravitySpinner             matlab.ui.control.Spinner
        GravitySpinnerLabel        matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Simulation Parameters
        params
        
        % Simulation variables
        dust; 
        kernels;

        % Callbacks
        play                    = false;
        trace                   = false;
        record                  = false;
        tab                     = 'Main';
        setup                   = false;
        

        % Timing globals
        TOTAL_TIME              = 0.0;
        firstFrame              = true;
        TIME                    = 0;
        drawRate                = 60;
        drawPeriod

        % CUDA KERNELS
        pcryAcc;
        pcryPos;

        % Axes objects
        dustAxes;
        wakeAxes;

        % OUTPUT AND INPUT FILES
        fileParam               = 'parameters.txt';
    end
    
    methods (Access = private) 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup app
            vdriadSetKernels(app);
            vdriadLoadParameters(app);

            % Setup plasma
            vdriadUpdateParams(app);
            [app.dust, app.params] = vdriadGenerateParticles(app.dust,app.params);

            % Setup display
            vdriadFormatMain(app);
            vdriadDrawCell(app.UIAxes,app.params);
            vdriadDrawDust(app);
        end

        % Value changed function: PlayButton
        function Main(app, event)
            drawCount = 0;

            app.play = app.PlayButton.Value;
            
            % Change the color of the lamp
            if app.play == true
                app.PlayLamp.Color = 'green';
            else
                app.PlayLamp.Color = 'red';
            end

            % Keep the simulation running
            while app.play == true
                % Calculate one timestep
                app.dust = vdriadCalculateForces(app.dust,app.params,app.pcryAcc);
                app.dust = vdriadCalculatePositions(app.dust,app.params,app.pcryPos,app.TOTAL_TIME);
                
                % Copy data from the GPU to the host
                app.dust = vdriadMemCpy(app.dust,'DtoH');

                % Draw Particles
                if drawCount == 0
                    vdriadDrawDust(app);
                end

                drawnow limitrate

                % Increase counters
                app.TOTAL_TIME = app.TOTAL_TIME + app.params.TIME_STEP;
                drawCount = mod(drawCount+1,app.drawPeriod);
            end
        end

        % Value changing function: GravitySpinner
        function GravitySpinnerValueChanging(app, event)
            app.params.GRAVITY = event.Value;
        end

        % Value changing function: PressureSpinner
        function PressureSpinnerValueChanging(app, event)
            app.params.GAS_PRESSURE = event.Value;
            vdriadUpdateParams(app);
        end

        % Button pushed function: ReplayButton
        function ReplayButtonPushed(app, event)
            % Reset timer
            app.TOTAL_TIME = 0.0;

            if app.NewseedCheckBox.Value
                % Generate new seed for particles
                app.dust = vdriadGenerateParticles(app.dust,app.params);
            else
                % Retrieve and load initial conditions
                app.dust.h_x         = app.dust.x0;
                app.dust.h_y         = app.dust.y0;
                app.dust.h_z         = app.dust.z0;
                
                app.dust = vdriadMemCpy(app.dust,'HtoD','all');
            end

            % Draw first frame
            vdriadDrawDust(app)
        end

        % Value changed function: PowerSpinner
        function PowerSpinnerValueChanged(app, event)
            app.params.CELL_POWER = event.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'GEC RF Reference Cell')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SimulationParametersPanel = uipanel(app.UIFigure);
            app.SimulationParametersPanel.Title = 'Simulation Parameters';
            app.GravitySpinnerLabel = uilabel(app.SimulationParametersPanel);
            app.GravitySpinnerLabel.Text = 'Gravity ';
            app.GravitySpinner = uispinner(app.SimulationParametersPanel);
            app.GravitySpinner.ValueChangingFcn = createCallbackFcn(app, @GravitySpinnerValueChanging, true);
            app.PressureSpinnerLabel = uilabel(app.SimulationParametersPanel);
            app.PressureSpinnerLabel.Text = 'Pressure ';
            app.PressureSpinner = uispinner(app.SimulationParametersPanel);
            app.PressureSpinner.ValueChangingFcn = createCallbackFcn(app, @PressureSpinnerValueChanging, true);
            app.PowerSpinnerLabel = uilabel(app.SimulationParametersPanel);
            app.PowerSpinnerLabel.Text = 'Power ';
            app.PowerSpinner = uispinner(app.SimulationParametersPanel);
            app.PowerSpinner.ValueChangedFcn = createCallbackFcn(app, @PowerSpinnerValueChanged, true);
            app.SimulationControlPanel = uipanel(app.UIFigure);
            app.SimulationControlPanel.Title = 'Simulation Control';
            app.PlayButton = uibutton(app.SimulationControlPanel, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @Main, true);
            app.PlayButton.Text = 'Play';
            app.PlayLamp = uilamp(app.SimulationControlPanel);
            app.ReplayButton = uibutton(app.SimulationControlPanel, 'push');
            app.ReplayButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayButtonPushed, true);
            app.ReplayButton.Text = 'Replay';
            app.NewseedCheckBox = uicheckbox(app.SimulationControlPanel);
            app.NewseedCheckBox.Text = 'New seed';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.IonDebyeGaugeLabel = uilabel(app.Panel);
            app.IonDebyeGaugeLabel.Text = 'Ion Debye';
            app.GaugeDebyeElectron = uigauge(app.Panel, 'linear');
            app.ElectronDebyeGaugeLabel = uilabel(app.Panel);
            app.ElectronDebyeGaugeLabel.Text = 'Electron Debye';
            app.GaugeDebyeIon = uigauge(app.Panel, 'linear');
            app.TabGroup01 = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup01);
            app.Tab.Title = 'Tab';
            app.ParticleTrajectoriesTab = uitab(app.TabGroup01);
            app.ParticleTrajectoriesTab.Title = 'Particle Trajectories';
            app.UITable = uitable(app.ParticleTrajectoriesTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VIONWAKE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end